classdef docScannerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentScanner           matlab.ui.Figure
        SeleccionararchivoButton  matlab.ui.control.Button
        imagenOriginal            matlab.ui.control.Image
        imagenFInal               matlab.ui.control.Image
        MetodoButtonGroup         matlab.ui.container.ButtonGroup
        ManualButton              matlab.ui.control.RadioButton
        RegionpropsButton         matlab.ui.control.RadioButton
        ProcesarButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
        originalImage % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         addpath('src/');

        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            fullPath = strcat(path,file);
            app.image = readImage(fullPath);

            % Mostramos la original
            app.imagenOriginal.ImageSource = app.image;

            app.originalImage = app.image;
        end

        % Selection changed function: MetodoButtonGroup
        function MetodoButtonGroupSelectionChanged(app, event)
            selectedButton = app.MetodoButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            % Aplicamos el preprocesado (y convertimos a binaria)
            app.image = preprocessing(app.image);

            % Detectamos las esquinas
            corners = detectCorners(app.image, app.MetodoButtonGroup.SelectedObject.Text);

            % Corregimos perspectiva
            app.image = fixPerspective(app.originalImage, corners);

            app.imagenFInal.ImageSource = app.image; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentScanner = uifigure('Visible', 'off');
            app.DocumentScanner.Name = 'Document Scanner';
            app.SeleccionararchivoButton = uibutton(app.DocumentScanner, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo ...';
            app.imagenOriginal = uiimage(app.DocumentScanner);
            app.imagenFInal = uiimage(app.DocumentScanner);
            app.MetodoButtonGroup = uibuttongroup(app.DocumentScanner);
            app.MetodoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MetodoButtonGroupSelectionChanged, true);
            app.MetodoButtonGroup.Title = 'Metodo';
            app.ManualButton = uiradiobutton(app.MetodoButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.RegionpropsButton = uiradiobutton(app.MetodoButtonGroup);
            app.RegionpropsButton.Text = 'Regionprops';
            app.ProcesarButton = uibutton(app.DocumentScanner, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            % Show the figure after all components are created
            app.DocumentScanner.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = docScannerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentScanner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentScanner)
        end
    end
end